classdef UNDXMGG_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure                   % UI Figure
        Problem                 matlab.ui.container.Panel          % Problem
        LabelNumericEditField   matlab.ui.control.Label            % # Vari...
        N_Variables             matlab.ui.control.NumericEditField % [0 Inf]
        LabelNumericEditField2  matlab.ui.control.Label            % # Cons...
        N_Constraints           matlab.ui.control.NumericEditField % [0 Inf]
        LabelEditField          matlab.ui.control.Label            % Fitnes...
        FitnessFunction         matlab.ui.control.EditField       
        LabelEditField2         matlab.ui.control.Label            % Decodi...
        DecodingFunction        matlab.ui.control.EditField       
        FitnessFunctionButton   matlab.ui.control.Button           % Select
        DecodingFunctionButton  matlab.ui.control.Button           % Select
        Label                   matlab.ui.control.Label            % UNDX/MGG
        RCGAParameters          matlab.ui.container.Panel          % RCGA P...
        Label15                 matlab.ui.control.Label            % Popula...
        PopulationSize          matlab.ui.control.NumericEditField % [0 Inf]
        Label16                 matlab.ui.control.Label            % Childr...
        ChildrenSize            matlab.ui.control.NumericEditField % [0 Inf]
        Label17                 matlab.ui.control.Label            % Pf
        Pf                      matlab.ui.control.NumericEditField % [0 Inf]
        UseRecommendedValueButton matlab.ui.control.Button % Use Recommende...
        Termination             matlab.ui.container.Panel          % Termin...
        Label18                 matlab.ui.control.Label            % Max # ...
        Max_N_Generation        matlab.ui.control.NumericEditField % [0 Inf]
        Label19                 matlab.ui.control.Label            % Max Ti...
        MaxTime                 matlab.ui.control.NumericEditField % [0 Inf]
        Label20                 matlab.ui.control.Label            % Value ...
        ValueToBeReached        matlab.ui.control.NumericEditField % [-Inf ...
        Output                  matlab.ui.container.Panel          % Output
        Label10                 matlab.ui.control.Label            % Transi...
        TransitionFileName      matlab.ui.control.EditField        % None
        Label11                 matlab.ui.control.Label            % Soluti...
        SolutionFileName        matlab.ui.control.EditField        % None
        Label12                 matlab.ui.control.Label            % Final ...
        FinalPopulationFileName matlab.ui.control.EditField        % None
        Label21                 matlab.ui.control.Label            % Output...
        OutputIntervalGeneration matlab.ui.control.NumericEditField % [0 Inf]
        RunButton               matlab.ui.control.Button           % Run
        ParallelSwitch          matlab.ui.control.Switch           % Serial...
        Label22                 matlab.ui.control.Label            % Seed
        Seed                    matlab.ui.control.NumericEditField % [0 Inf]
        ResetButton             matlab.ui.control.Button           % Reset
    end

    properties (Access = public)
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % RunButton button pushed function
        function RunButtonPushed(app)
            addpath(genpath('D:\cygwin64\home\maeda\GitHub\RCGAToolbox\source\Shared'));
            addpath(genpath('D:\cygwin64\home\maeda\GitHub\RCGAToolbox\source\UNDXMGG'));
            addpath(genpath('D:\cygwin64\home\maeda\GitHub\RCGAToolbox\example'));
            Param.n_gene = app.N_Variables.Value;
            Param.n_constraint = app.N_Constraints.Value;
            Param.fitnessfun = @ConstrainedSphere; %app.FitnessFunction.Value;
            Param.decodingfun = @Sphere_decode;%app.DecodingFunction.Value;
            Param.n_population = app.PopulationSize.Value;
            Param.n_children = app.ChildrenSize.Value;
            Param.Pf = app.Pf.Value;
            Param.n_generation = app.Max_N_Generation.Value;
            Param.t_limit = 60 * app.MaxTime.Value;
            Param.vtr = app.ValueToBeReached.Value;
            Param.output_intvl = app.OutputIntervalGeneration.Value;
            Param.out_transition = app.TransitionFileName.Value;
            Param.out_solution = app.SolutionFileName.Value;
            Param.out_population = app.FinalPopulationFileName.Value;
%             Param.interimreportfun = @(x) x;
            rng(app.Seed.Value);
            
            if strcmp(app.ParallelSwitch.Value,'Parallel')
                Param.par = 1;
            else
                Param.par = 0;
            end
            [ best, Population ] = UNDXMGG(Param);
            
        end

        % UseRecommendedValueButton button pushed function
        function UseRecommendedValueButtonButtonPushed(app)
            msg = 'The current values for "Population Size", "Children Size", and "Pf" will be lost. Would you like to continue?';
            title = 'Confirmation';
            selection = questdlg(msg,title,...
                'Yes','No','Yes');
            if strcmp(selection,'No')
                return;
            end
            app.PopulationSize.Value = 20 * app.N_Variables.Value;
            app.ChildrenSize.Value = 10 * app.N_Variables.Value;
            if app.N_Constraints.Value > 0
                app.Pf.Value = 0.45;
            else
                app.Pf.Value = 0;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.Problem = uipanel(app.UIFigure);
            app.Problem.Title = 'Problem';
            app.Problem.Units = 'pixels';
            app.LabelNumericEditField = uilabel(app.Problem);
            app.LabelNumericEditField.Text = '# Variables';
            app.N_Variables = uieditfield(app.Problem, 'numeric');
            app.N_Variables.Limits = [0 Inf];
            app.N_Variables.ValueDisplayFormat = '%.0f';
            app.LabelNumericEditField2 = uilabel(app.Problem);
            app.LabelNumericEditField2.Text = '# Constraints';
            app.N_Constraints = uieditfield(app.Problem, 'numeric');
            app.N_Constraints.Limits = [0 Inf];
            app.N_Constraints.ValueDisplayFormat = '%.0f';
            app.LabelEditField = uilabel(app.Problem);
            app.LabelEditField.Text = 'Fitness Function';
            app.FitnessFunction = uieditfield(app.Problem, 'text');
            app.LabelEditField2 = uilabel(app.Problem);
            app.LabelEditField2.Text = 'Decoding Function';
            app.DecodingFunction = uieditfield(app.Problem, 'text');
            app.FitnessFunctionButton = uibutton(app.Problem, 'push');
            app.FitnessFunctionButton.Text = 'Select';
            app.DecodingFunctionButton = uibutton(app.Problem, 'push');
            app.DecodingFunctionButton.Text = 'Select';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'UNDX/MGG';
            app.RCGAParameters = uipanel(app.UIFigure);
            app.RCGAParameters.Title = 'RCGA Parameters';
            app.RCGAParameters.Units = 'pixels';
            app.Label15 = uilabel(app.RCGAParameters);
            app.Label15.Text = 'Population Size';
            app.PopulationSize = uieditfield(app.RCGAParameters, 'numeric');
            app.PopulationSize.Limits = [0 Inf];
            app.PopulationSize.ValueDisplayFormat = '%.0f';
            app.PopulationSize.Value = 200;
            app.Label16 = uilabel(app.RCGAParameters);
            app.Label16.Text = 'Children Size';
            app.ChildrenSize = uieditfield(app.RCGAParameters, 'numeric');
            app.ChildrenSize.Limits = [0 Inf];
            app.ChildrenSize.ValueDisplayFormat = '%.0f';
            app.ChildrenSize.Value = 200;
            app.Label17 = uilabel(app.RCGAParameters);
            app.Label17.Text = 'Pf';
            app.Pf = uieditfield(app.RCGAParameters, 'numeric');
            app.Pf.Limits = [0 Inf];
            app.Pf.ValueDisplayFormat = '%11.16g';
            app.Pf.Value = 0.45;
            app.UseRecommendedValueButton = uibutton(app.RCGAParameters, 'push');
            app.UseRecommendedValueButton.ButtonPushedFcn = createCallbackFcn(app, @UseRecommendedValueButtonButtonPushed);
            app.UseRecommendedValueButton.Text = 'Use Recommended Values';
            app.Termination = uipanel(app.UIFigure);
            app.Termination.Title = 'Termination';
            app.Termination.Units = 'pixels';
            app.Label18 = uilabel(app.Termination);
            app.Label18.Text = 'Max # Generations';
            app.Max_N_Generation = uieditfield(app.Termination, 'numeric');
            app.Max_N_Generation.Limits = [0 Inf];
            app.Max_N_Generation.ValueDisplayFormat = '%.0f';
            app.Max_N_Generation.Value = 1000;
            app.Label19 = uilabel(app.Termination);
            app.Label19.Text = 'Max Time (min)';
            app.MaxTime = uieditfield(app.Termination, 'numeric');
            app.MaxTime.Limits = [0 Inf];
            app.MaxTime.ValueDisplayFormat = '%11.16g';
            app.MaxTime.Value = 1;
            app.Label20 = uilabel(app.Termination);
            app.Label20.Text = 'Value To Be Reached';
            app.ValueToBeReached = uieditfield(app.Termination, 'numeric');
            app.ValueToBeReached.ValueDisplayFormat = '%e';
            app.Output = uipanel(app.UIFigure);
            app.Output.Title = 'Output';
            app.Output.Units = 'pixels';
            app.Label10 = uilabel(app.Output);
            app.Label10.Text = 'Transition File Name';
            app.TransitionFileName = uieditfield(app.Output, 'text');
            app.TransitionFileName.Value = 'None';
            app.Label11 = uilabel(app.Output);
            app.Label11.Text = 'Solution File Name';
            app.SolutionFileName = uieditfield(app.Output, 'text');
            app.SolutionFileName.Value = 'None';
            app.Label12 = uilabel(app.Output);
            app.Label12.Text = 'Final Population File Name';
            app.FinalPopulationFileName = uieditfield(app.Output, 'text');
            app.FinalPopulationFileName.Value = 'None';
            app.Label21 = uilabel(app.Output);
            app.Label21.Text = 'Output Interval Generation';
            app.OutputIntervalGeneration = uieditfield(app.Output, 'numeric');
            app.OutputIntervalGeneration.Limits = [0 Inf];
            app.OutputIntervalGeneration.ValueDisplayFormat = '%.0f';
            app.OutputIntervalGeneration.Value = 5;
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed);
            app.RunButton.Text = 'Run';
            app.ParallelSwitch = uiswitch(app.UIFigure, 'slider');
            app.ParallelSwitch.Items = {'Serial', 'Parallel'};
            app.ParallelSwitch.Value = 'Serial';
            app.Label22 = uilabel(app.UIFigure);
            app.Label22.Text = 'Seed';
            app.Seed = uieditfield(app.UIFigure, 'numeric');
            app.Seed.Limits = [0 Inf];
            app.Seed.ValueDisplayFormat = '%.0f';
            app.Seed.Value = 1;
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.Text = 'Reset';
        end
    end

    methods (Access = public)

        % Construct app
        function app = UNDXMGG_GUI()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end