classdef RCGAToolbox_MissionControl_PE_R2021a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        LoadButton                matlab.ui.control.Button
        SaveButton                matlab.ui.control.Button
        Label27                   matlab.ui.control.Label
        Algorithm                 matlab.ui.container.Panel
        AlgorithmSwitch           matlab.ui.control.Switch
        Label25                   matlab.ui.control.Label
        Label24                   matlab.ui.control.Label
        Panel                     matlab.ui.container.Panel
        CreateButton              matlab.ui.control.Button
        ExecutableScriptFileName  matlab.ui.control.EditField
        LabelEditField4           matlab.ui.control.Label
        Others                    matlab.ui.container.Panel
        FastFlag                  matlab.ui.control.NumericEditField
        Label29                   matlab.ui.control.Label
        LabelSwitch               matlab.ui.control.Label
        LocalSwitch               matlab.ui.control.Switch
        Seed                      matlab.ui.control.NumericEditField
        Label22                   matlab.ui.control.Label
        N_Workers                 matlab.ui.control.NumericEditField
        LabelNumericEditField3    matlab.ui.control.Label
        Label23                   matlab.ui.control.Label
        ResetButton               matlab.ui.control.Button
        LaunchButton              matlab.ui.control.Button
        Output                    matlab.ui.container.Panel
        ReportFileName            matlab.ui.control.EditField
        LabelEditField3           matlab.ui.control.Label
        OutputIntervalGeneration  matlab.ui.control.NumericEditField
        Label21                   matlab.ui.control.Label
        FinalPopulationFileName   matlab.ui.control.EditField
        Label12                   matlab.ui.control.Label
        BestIndividualFileName    matlab.ui.control.EditField
        Label11                   matlab.ui.control.Label
        TransitionFileName        matlab.ui.control.EditField
        Label10                   matlab.ui.control.Label
        Termination               matlab.ui.container.Panel
        ValueToBeReached          matlab.ui.control.NumericEditField
        Label20                   matlab.ui.control.Label
        MaxTime                   matlab.ui.control.NumericEditField
        Label19                   matlab.ui.control.Label
        MaxGenerations            matlab.ui.control.NumericEditField
        Label18                   matlab.ui.control.Label
        RCGAParameters            matlab.ui.container.Panel
        ChildrenSize              matlab.ui.control.NumericEditField
        Label16                   matlab.ui.control.Label
        PopulationSize            matlab.ui.control.NumericEditField
        Label15                   matlab.ui.control.Label
        Problem                   matlab.ui.container.Panel
        MeasurementButton         matlab.ui.control.Button
        Measurement               matlab.ui.control.EditField
        Label28                   matlab.ui.control.Label
        DecodingFunctionButton    matlab.ui.control.Button
        ModelButton               matlab.ui.control.Button
        DecodingFunction          matlab.ui.control.EditField
        LabelEditField2           matlab.ui.control.Label
        Model                     matlab.ui.control.EditField
        LabelEditField            matlab.ui.control.Label
    end

    properties (Access = public)
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: LaunchButton
        function LaunchButtonPushed(app, event)
            [modelpath, modelfile, modelext] = fileparts(app.Model.Value);
            addpath(modelpath);
            if exist(modelfile)
                modelfile = str2func(modelfile);
            else
                modelfile = strcat(modelpath,'/',modelfile,modelext);
            end
            
            [decodingpath, decodingfile] = fileparts(app.DecodingFunction.Value);
            addpath(decodingpath);
            decodingfun = str2func(decodingfile);
            
            measurement = app.Measurement.Value;
            
            if strcmp(app.AlgorithmSwitch.Value,'UNDX/MGG')
                RCGA_PE = @RCGA_UNDXMGG_PE;
            else
                RCGA_PE = @RCGA_REXstarJGG_PE;
            end
            opts.n_population = app.PopulationSize.Value;
            opts.n_children = app.ChildrenSize.Value;
            opts.maxgen = app.MaxGenerations.Value;
            opts.maxtime = 60 * app.MaxTime.Value;
            opts.vtr = app.ValueToBeReached.Value;
            opts.output_intvl = app.OutputIntervalGeneration.Value;
            opts.out_transition = app.TransitionFileName.Value;
            opts.out_best = app.BestIndividualFileName.Value;
            opts.out_population = app.FinalPopulationFileName.Value;
            opts.out_report = app.ReportFileName.Value;
            rng(app.Seed.Value);
            opts.n_par = app.N_Workers.Value;
            fast_flag = app.FastFlag.Value;
            if strcmp(app.LocalSwitch.Value,'On')
                opts.local = 1;
            else
                opts.local = 0;
            end
            
            clear RCGAssr;
            Results = RCGA_PE(modelfile,decodingfun,measurement,fast_flag,[],opts);
            
            figure;
            plot(Results.Transition.time,Results.Transition.f,'LineWidth',2);
            set(gca,'FontSize',10,'FontName','Arial');
            title('Convergence Curve');
            xlabel('Time (sec)');
            ylabel('Objective Function');
            
            rmpath(modelpath);
            if ~strcmp(modelpath,decodingpath)
                rmpath(decodingpath);
            end
            
        end

        % Button pushed function: ModelButton
        function ModelButtonPushed(app, event)
%             fileext1 = sprintf('*.m;*.mlx;*.%s',mexext);
%             description1 = sprintf('MATLAB Function Files (%s)',fileext1);
%             fileext2 = '*.sbml;*.xml';
%             description2 = sprintf('SBML Files (%s)',fileext2);
%             fileext3 = '*.c';
%             description3 = sprintf('C Files (%s)',fileext3);
%             [file,path,~] = uigetfile( ...
%                 {fileext1,description1;
%                 fileext2,description2;
%                 fileext3,description3;
%                 '*.*','All Files (*.*)'}, ...
%                 'Select a File');
            fileext = sprintf('*.m;*.mlx;*.%s;*.sbml;*.xml;*.c',mexext);
            description = sprintf('Model Files (%s)',fileext);
            [file,path,~] = uigetfile( ...
                {fileext,description;
                '*.*','All Files (*.*)'}, ...
                'Select a File');
            if isequal(file,0) || isequal(path,0)
                return;
            end
            [~, file, ext] = fileparts(file);
            app.Model.Value = strcat(path,file,ext);
        end

        % Button pushed function: DecodingFunctionButton
        function DecodingFunctionButtonPushed(app, event)
            ext = mexext;
            fileext = sprintf('*.m;*.mlx;*.%s',ext);
            description = sprintf('MATLAB Function Files (%s)',fileext);
            [file,path,~] = uigetfile( ...
                {fileext,description;
                '*.*','All Files (*.*)'}, ...
                'Select a File');
            if isequal(file,0) || isequal(path,0)
                return;
            end
            [~, file, ] = fileparts(file);
            app.DecodingFunction.Value = strcat(path,file);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            msg = 'The current settings will be lost. Would you like to continue?';
            title = 'Confirmation';
            selection = questdlg(msg,title,...
                'Yes','No','Yes');
            if strcmp(selection,'No')
                return;
            end
            app.Model.Value = 'Model_Example_odefun.m';
            app.DecodingFunction.Value = 'Decoding_Example.m';
            app.Measurement.Value = 'Measurement_Example.csv';
            app.AlgorithmSwitch.Value = 'REXstar/JGG';
            app.PopulationSize.Value = 50;
            app.ChildrenSize.Value = 25;
            app.MaxGenerations.Value = 200;
            app.MaxTime.Value = 1;
            app.ValueToBeReached.Value = 0;
            app.OutputIntervalGeneration.Value = 1;
            app.TransitionFileName.Value = 'Transition.txt';
            app.BestIndividualFileName.Value = 'BestIndividual.txt';
            app.FinalPopulationFileName.Value = 'FinalPopulation.txt';
            app.ReportFileName.Value = 'Report.mat';
            app.Seed.Value = 0;
            app.N_Workers.Value = 1;
            app.FastFlag.Value = 0;
            app.LocalSwitch.Value = 'Off';
            app.ExecutableScriptFileName.Value = 'CreatedExecutableScript.m';
        end

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
            RCGAcreateExecutableScript_PE_R2016a(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            [modelpath, modelfile, modelext] = fileparts(app.Model.Value);
            modelfile = strcat(modelfile,modelext);
            [decodingpath, decodingfile] = fileparts(app.DecodingFunction.Value);
            decodingfun = str2func(decodingfile);
            measurement = app.Measurement.Value;
            
            if strcmp(app.AlgorithmSwitch.Value,'UNDX/MGG')
                RCGA = @RCGA_UNDXMGG_PE;
            else
                RCGA = @RCGA_REXstarJGG_PE;
            end
            opts.n_population = app.PopulationSize.Value;
            opts.n_children = app.ChildrenSize.Value;
            opts.maxgen = app.MaxGenerations.Value;
            opts.maxtime = 60 * app.MaxTime.Value;
            opts.vtr = app.ValueToBeReached.Value;
            opts.output_intvl = app.OutputIntervalGeneration.Value;
            opts.out_transition = app.TransitionFileName.Value;
            opts.out_best = app.BestIndividualFileName.Value;
            opts.out_population = app.FinalPopulationFileName.Value;
            opts.out_report = app.ReportFileName.Value;
            seed = app.Seed.Value;
            opts.n_par = app.N_Workers.Value;
            fast_flag = app.FastFlag.Value;
            if strcmp(app.LocalSwitch.Value,'On')
                opts.local = 1;
            else
                opts.local = 0;
            end
            executablescriptfilename = app.ExecutableScriptFileName.Value;
            
            varlist = {'RCGA','opts','modelpath','modelfile','decodingpath','decodingfun','measurement','seed','fast_flag','executablescriptfilename'};
            uisave(varlist,'SavedRCGAsettings.mat');
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            varlist = {'RCGA','opts','modelpath','modelfile','decodingpath','decodingfun','measurement','seed','fast_flag','executablescriptfilename'};
            uiopen('load');
            for i = 1 : length(varlist)
                varname = char(varlist(i));
                if ~exist(varname,'var')
                    error('Loaded MAT file does not have the variable "%s".\nWas the selected file created by RCGAToolbox Mission Control PE?',varname);
                end
            end
            
            if isempty(modelpath)
                app.Model.Value = modelfile;
            else
                app.Model.Value = strcat(modelpath,'/',modelfile);
            end
            if isempty(decodingpath)
                app.DecodingFunction.Value = func2str(decodingfun);
            else
                app.DecodingFunction.Value = strcat(decodingpath,'/',func2str(decodingfun));
            end
            app.Measurement.Value = measurement;
            if strcmp(func2str(RCGA),'RCGA_UNDXMGG_PE')
                app.AlgorithmSwitch.Value = 'UNDX/MGG';
            else
                app.AlgorithmSwitch.Value = 'REXstar/JGG';
            end
            app.PopulationSize.Value = opts.n_population;
            app.ChildrenSize.Value = opts.n_children;
            app.MaxGenerations.Value = opts.maxgen;
            app.MaxTime.Value = opts.maxtime / 60;
            app.ValueToBeReached.Value = opts.vtr;
            app.OutputIntervalGeneration.Value = opts.output_intvl;
            app.TransitionFileName.Value = opts.out_transition;
            app.BestIndividualFileName.Value = opts.out_best;
            app.FinalPopulationFileName.Value = opts.out_population;
            app.ReportFileName.Value = opts.out_report;
            app.Seed.Value = seed;
            app.N_Workers.Value = opts.n_par;
            app.FastFlag.Value = fast_flag;
            if opts.local == 1
                app.LocalSwitch.Value = 'On';
            else
                app.LocalSwitch.Value = 'Off';
            end
            app.ExecutableScriptFileName.Value = executablescriptfilename;
        end

        % Button pushed function: MeasurementButton
        function MeasurementButtonPushed(app, event)
            fileext = '*.xls;*.csv';
            description = sprintf('Measurement Files (%s)',fileext);
            [file,path,~] = uigetfile( ...
                {fileext,description;
                '*.*','All Files (*.*)'}, ...
                'Select a File');
            if isequal(file,0) || isequal(path,0)
                return;
            end
            app.Measurement.Value = strcat(path,file);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'RCGAToolbox GUI';
            app.Problem = uipanel(app.UIFigure);
            app.Problem.Title = 'Problem';
            app.LabelEditField = uilabel(app.Problem);
            app.LabelEditField.Text = 'Model File';
            app.Model = uieditfield(app.Problem, 'text');
            app.Model.Value = 'Model_Example_odefun.m';
            app.LabelEditField2 = uilabel(app.Problem);
            app.LabelEditField2.Text = 'Decoding Function File';
            app.DecodingFunction = uieditfield(app.Problem, 'text');
            app.DecodingFunction.Value = 'Decoding_Example.m';
            app.ModelButton = uibutton(app.Problem, 'push');
            app.ModelButton.ButtonPushedFcn = createCallbackFcn(app, @ModelButtonPushed, true);
            app.ModelButton.Text = 'Select';
            app.DecodingFunctionButton = uibutton(app.Problem, 'push');
            app.DecodingFunctionButton.ButtonPushedFcn = createCallbackFcn(app, @DecodingFunctionButtonPushed, true);
            app.DecodingFunctionButton.Text = 'Select';
            app.Label28 = uilabel(app.Problem);
            app.Label28.Text = 'Measurement File';
            app.Measurement = uieditfield(app.Problem, 'text');
            app.Measurement.Value = 'Measurement_Example.csv';
            app.MeasurementButton = uibutton(app.Problem, 'push');
            app.MeasurementButton.ButtonPushedFcn = createCallbackFcn(app, @MeasurementButtonPushed, true);
            app.MeasurementButton.Text = 'Select';
            app.RCGAParameters = uipanel(app.UIFigure);
            app.RCGAParameters.Title = 'RCGA Parameters';
            app.Label15 = uilabel(app.RCGAParameters);
            app.Label15.Text = 'Population Size';
            app.PopulationSize = uieditfield(app.RCGAParameters, 'numeric');
            app.PopulationSize.Limits = [0 Inf];
            app.PopulationSize.ValueDisplayFormat = '%.0f';
            app.PopulationSize.Value = 50;
            app.Label16 = uilabel(app.RCGAParameters);
            app.Label16.Text = '# Children per Generation';
            app.ChildrenSize = uieditfield(app.RCGAParameters, 'numeric');
            app.ChildrenSize.Limits = [0 Inf];
            app.ChildrenSize.ValueDisplayFormat = '%.0f';
            app.ChildrenSize.Value = 25;
            app.Termination = uipanel(app.UIFigure);
            app.Termination.Title = 'Termination';
            app.Label18 = uilabel(app.Termination);
            app.Label18.Text = 'Max # Generations';
            app.MaxGenerations = uieditfield(app.Termination, 'numeric');
            app.MaxGenerations.Limits = [0 Inf];
            app.MaxGenerations.ValueDisplayFormat = '%.0f';
            app.MaxGenerations.Value = 200;
            app.Label19 = uilabel(app.Termination);
            app.Label19.Text = 'Max Time (min)';
            app.MaxTime = uieditfield(app.Termination, 'numeric');
            app.MaxTime.Limits = [0 Inf];
            app.MaxTime.ValueDisplayFormat = '%11.16g';
            app.MaxTime.Value = 1;
            app.Label20 = uilabel(app.Termination);
            app.Label20.Text = 'Value To Be Reached';
            app.ValueToBeReached = uieditfield(app.Termination, 'numeric');
            app.ValueToBeReached.ValueDisplayFormat = '%e';
            app.Output = uipanel(app.UIFigure);
            app.Output.Title = 'Output';
            app.Label10 = uilabel(app.Output);
            app.Label10.Text = 'Transition File Name';
            app.TransitionFileName = uieditfield(app.Output, 'text');
            app.TransitionFileName.Value = 'Transition.txt';
            app.Label11 = uilabel(app.Output);
            app.Label11.Text = 'Best Individual File Name';
            app.BestIndividualFileName = uieditfield(app.Output, 'text');
            app.BestIndividualFileName.Value = 'BestIndividual.txt';
            app.Label12 = uilabel(app.Output);
            app.Label12.Text = 'Final Population File Name';
            app.FinalPopulationFileName = uieditfield(app.Output, 'text');
            app.FinalPopulationFileName.Value = 'FinalPopulation.txt';
            app.Label21 = uilabel(app.Output);
            app.Label21.Text = 'Output Interval Generation';
            app.OutputIntervalGeneration = uieditfield(app.Output, 'numeric');
            app.OutputIntervalGeneration.Limits = [0 Inf];
            app.OutputIntervalGeneration.ValueDisplayFormat = '%.0f';
            app.OutputIntervalGeneration.Value = 1;
            app.LabelEditField3 = uilabel(app.Output);
            app.LabelEditField3.Text = 'Report File Name';
            app.ReportFileName = uieditfield(app.Output, 'text');
            app.ReportFileName.Value = 'Report.mat';
            app.LaunchButton = uibutton(app.UIFigure, 'push');
            app.LaunchButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchButtonPushed, true);
            app.LaunchButton.Text = 'Launch';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Label23 = uilabel(app.UIFigure);
            app.Label23.Enable = 'off';
            app.Label23.Text = 'Messages will be shown in MATLAB Command Window.';
            app.Others = uipanel(app.UIFigure);
            app.Others.Title = 'Others';
            app.LabelNumericEditField3 = uilabel(app.Others);
            app.LabelNumericEditField3.Text = '# Workers ';
            app.N_Workers = uieditfield(app.Others, 'numeric');
            app.N_Workers.Limits = [1 Inf];
            app.N_Workers.ValueDisplayFormat = '%.0f';
            app.N_Workers.Value = 1;
            app.Label22 = uilabel(app.Others);
            app.Label22.Text = 'Random Seed';
            app.Seed = uieditfield(app.Others, 'numeric');
            app.Seed.Limits = [0 Inf];
            app.Seed.ValueDisplayFormat = '%.0f';
            app.LocalSwitch = uiswitch(app.Others, 'slider');
            app.LabelSwitch = uilabel(app.Others);
            app.LabelSwitch.Text = 'Local Optimizer';
            app.Label29 = uilabel(app.Others);
            app.Label29.Text = 'Fast Flag';
            app.FastFlag = uieditfield(app.Others, 'numeric');
            app.FastFlag.Limits = [0 2];
            app.FastFlag.ValueDisplayFormat = '%.0f';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Create an Executable Script With the Current Settings';
            app.LabelEditField4 = uilabel(app.Panel);
            app.LabelEditField4.Text = 'File Name';
            app.ExecutableScriptFileName = uieditfield(app.Panel, 'text');
            app.ExecutableScriptFileName.Value = 'CreatedExecutableScript.m';
            app.CreateButton = uibutton(app.Panel, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Text = 'Create';
            app.Label24 = uilabel(app.UIFigure);
            app.Label24.Enable = 'off';
            app.Label24.Text = 'Press ''Control + C'' to abort a running RCGA.';
            app.Label25 = uilabel(app.UIFigure);
            app.Label25.Text = 'RCGAToolbox Mission Control PE';
            app.Algorithm = uipanel(app.UIFigure);
            app.Algorithm.Title = 'Algorithm';
            app.AlgorithmSwitch = uiswitch(app.Algorithm, 'slider');
            app.AlgorithmSwitch.Items = {'UNDX/MGG', 'REXstar/JGG'};
            app.AlgorithmSwitch.Value = 'REXstar/JGG';
            app.Label27 = uilabel(app.UIFigure);
            app.Label27.Enable = 'off';
            app.Label27.Text = 'For a demo, press [Reset] and then [Launch].';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RCGAToolbox_MissionControl_PE_R2021a

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end