classdef RCGAToolbox_MissionControl_R2021a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        LoadButton                 matlab.ui.control.Button
        SaveButton                 matlab.ui.control.Button
        Label27                    matlab.ui.control.Label
        Algorithm                  matlab.ui.container.Panel
        AlgorithmSwitch            matlab.ui.control.Switch
        Label25                    matlab.ui.control.Label
        Label24                    matlab.ui.control.Label
        Panel                      matlab.ui.container.Panel
        CreateButton               matlab.ui.control.Button
        ExecutableScriptFileName   matlab.ui.control.EditField
        LabelEditField4            matlab.ui.control.Label
        Others                     matlab.ui.container.Panel
        LabelSwitch                matlab.ui.control.Label
        LocalSwitch                matlab.ui.control.Switch
        Seed                       matlab.ui.control.NumericEditField
        Label22                    matlab.ui.control.Label
        N_Workers                  matlab.ui.control.NumericEditField
        LabelNumericEditField3     matlab.ui.control.Label
        Label23                    matlab.ui.control.Label
        ResetButton                matlab.ui.control.Button
        LaunchButton               matlab.ui.control.Button
        Output                     matlab.ui.container.Panel
        ReportFileName             matlab.ui.control.EditField
        LabelEditField3            matlab.ui.control.Label
        OutputIntervalGeneration   matlab.ui.control.NumericEditField
        Label21                    matlab.ui.control.Label
        FinalPopulationFileName    matlab.ui.control.EditField
        Label12                    matlab.ui.control.Label
        BestIndividualFileName     matlab.ui.control.EditField
        Label11                    matlab.ui.control.Label
        TransitionFileName         matlab.ui.control.EditField
        Label10                    matlab.ui.control.Label
        Termination                matlab.ui.container.Panel
        ValueToBeReached           matlab.ui.control.NumericEditField
        Label20                    matlab.ui.control.Label
        MaxTime                    matlab.ui.control.NumericEditField
        Label19                    matlab.ui.control.Label
        MaxGenerations             matlab.ui.control.NumericEditField
        Label18                    matlab.ui.control.Label
        RCGAParameters             matlab.ui.container.Panel
        UseRecommendedValueButton  matlab.ui.control.Button
        Pf                         matlab.ui.control.NumericEditField
        Label17                    matlab.ui.control.Label
        ChildrenSize               matlab.ui.control.NumericEditField
        Label16                    matlab.ui.control.Label
        PopulationSize             matlab.ui.control.NumericEditField
        Label15                    matlab.ui.control.Label
        Problem                    matlab.ui.container.Panel
        DecodingFunctionButton     matlab.ui.control.Button
        FitnessFunctionButton      matlab.ui.control.Button
        DecodingFunction           matlab.ui.control.EditField
        LabelEditField2            matlab.ui.control.Label
        FitnessFunction            matlab.ui.control.EditField
        LabelEditField             matlab.ui.control.Label
        N_Constraints              matlab.ui.control.NumericEditField
        LabelNumericEditField2     matlab.ui.control.Label
        N_Variables                matlab.ui.control.NumericEditField
        LabelNumericEditField      matlab.ui.control.Label
    end

    properties (Access = public)
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: LaunchButton
        function LaunchButtonPushed(app, event)
            problem.n_gene = app.N_Variables.Value;
            problem.n_constraint = app.N_Constraints.Value;
            [fitnesspath, fitnessfile] = fileparts(app.FitnessFunction.Value);
            addpath(fitnesspath);
            problem.fitnessfun = str2func(fitnessfile);
            [decodingpath, decodingfile] = fileparts(app.DecodingFunction.Value);
            addpath(decodingpath);
            problem.decodingfun = str2func(decodingfile);
            if strcmp(app.AlgorithmSwitch.Value,'UNDX/MGG')
                RCGA = @RCGA_UNDXMGG;
            else
                RCGA = @RCGA_REXstarJGG;
            end
            opts.n_population = app.PopulationSize.Value;
            opts.n_children = app.ChildrenSize.Value;
            opts.Pf = app.Pf.Value;
            opts.maxgen = app.MaxGenerations.Value;
            opts.maxtime = 60 * app.MaxTime.Value;
            opts.vtr = app.ValueToBeReached.Value;
            opts.output_intvl = app.OutputIntervalGeneration.Value;
            opts.out_transition = app.TransitionFileName.Value;
            opts.out_best = app.BestIndividualFileName.Value;
            opts.out_population = app.FinalPopulationFileName.Value;
            opts.out_report = app.ReportFileName.Value;
            rng(app.Seed.Value);
            opts.n_par = app.N_Workers.Value;
            if strcmp(app.LocalSwitch.Value,'On')
                opts.local = 1;
            else
                opts.local = 0;
            end
            
            Results = RCGA(problem,opts);
           
            figure;
            plot(Results.Transition.time,Results.Transition.f,'LineWidth',2);
            set(gca,'FontSize',10,'FontName','Arial');
            title('Convergence Curve');
            xlabel('Time (sec)');
            ylabel('Objective Function');
            
            rmpath(fitnesspath);
            if ~strcmp(fitnesspath,decodingpath)
                decodingpath(fitnesspath);
            end
            
        end

        % Button pushed function: UseRecommendedValueButton
        function UseRecommendedValueButtonPushed(app, event)
            msg = 'The current values for "Population Size", "Children Size", and "Pf" will be lost. Would you like to continue?';
            title = 'Confirmation';
            selection = questdlg(msg,title,...
                'Yes','No','Yes');
            if strcmp(selection,'No')
                return;
            end
            app.PopulationSize.Value = 20 * app.N_Variables.Value; % Recommended by Kobayashi 2009
            app.ChildrenSize.Value = 5 * app.N_Variables.Value; % Recommended by Kobayashi 2009
            if app.N_Constraints.Value > 0
                app.Pf.Value = 0.45;
            else
                app.Pf.Value = 0;
            end
        end

        % Button pushed function: FitnessFunctionButton
        function FitnessFunctionButtonPushed(app, event)
            ext = mexext;
            fileext = sprintf('*.m;*.mlx;*.%s',ext);
            description = sprintf('MATLAB Function Files (%s)',fileext);
            [file,path,~] = uigetfile( ...
                {fileext,description;
                '*.*','All Files (*.*)'}, ...
                'Select a File');
            if isequal(file,0) || isequal(path,0)
                return;
            end
            [~, file] = fileparts(file);
            app.FitnessFunction.Value = strcat(path,file);
        end

        % Button pushed function: DecodingFunctionButton
        function DecodingFunctionButtonPushed(app, event)
            ext = mexext;
            fileext = sprintf('*.m;*.mlx;*.%s',ext);
            description = sprintf('MATLAB Function Files (%s)',fileext);
            [file,path,~] = uigetfile( ...
                {fileext,description;
                '*.*','All Files (*.*)'}, ...
                'Select a File');
            if isequal(file,0) || isequal(path,0)
                return;
            end
            [~, file] = fileparts(file);
            app.DecodingFunction.Value = strcat(path,file);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            msg = 'The current settings will be lost. Would you like to continue?';
            title = 'Confirmation';
            selection = questdlg(msg,title,...
                'Yes','No','Yes');
            if strcmp(selection,'No')
                return;
            end
            app.N_Variables.Value = 10;
            app.N_Constraints.Value = 2;
            app.FitnessFunction.Value = 'Fitness_Example.m';
            app.DecodingFunction.Value = 'Decoding_Example.m';
            app.AlgorithmSwitch.Value = 'REXstar/JGG';
            app.PopulationSize.Value = 300;
            app.ChildrenSize.Value = app.PopulationSize.Value;
            app.Pf.Value = 0.45;
            app.MaxGenerations.Value = 1000;
            app.MaxTime.Value = 1;
            app.ValueToBeReached.Value = 0;
            app.OutputIntervalGeneration.Value = 10;
            app.TransitionFileName.Value = 'Transition.txt';
            app.BestIndividualFileName.Value = 'BestIndividual.txt';
            app.FinalPopulationFileName.Value = 'FinalPopulation.txt';
            app.ReportFileName.Value = 'Report.mat';
            app.Seed.Value = 0;
            app.N_Workers.Value = 1;
            app.LocalSwitch.Value = 'Off';
            app.ExecutableScriptFileName.Value = 'CreatedExecutableScript.m';
        end

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
            RCGAcreateExecutableScript_R2016a(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            problem.n_gene = app.N_Variables.Value;
            problem.n_constraint = app.N_Constraints.Value;
            [fitnesspath, fitnessfile] = fileparts(app.FitnessFunction.Value);
            problem.fitnessfun = str2func(fitnessfile);
            [decodingpath, decodingfile] = fileparts(app.DecodingFunction.Value);
            problem.decodingfun = str2func(decodingfile);
            if strcmp(app.AlgorithmSwitch.Value,'UNDX/MGG')
                RCGA = @RCGA_UNDXMGG;
            else
                RCGA = @RCGA_REXstarJGG;
            end
            opts.n_population = app.PopulationSize.Value;
            opts.n_children = app.ChildrenSize.Value;
            opts.Pf = app.Pf.Value;
            opts.maxgen = app.MaxGenerations.Value;
            opts.maxtime = 60 * app.MaxTime.Value;
            opts.vtr = app.ValueToBeReached.Value;
            opts.output_intvl = app.OutputIntervalGeneration.Value;
            opts.out_transition = app.TransitionFileName.Value;
            opts.out_best = app.BestIndividualFileName.Value;
            opts.out_population = app.FinalPopulationFileName.Value;
            opts.out_report = app.ReportFileName.Value;
            seed = app.Seed.Value;
            opts.n_par = app.N_Workers.Value;
            if strcmp(app.LocalSwitch.Value,'On')
                opts.local = 1;
            else
                opts.local = 0;
            end
            executablescriptfilename = app.ExecutableScriptFileName.Value;
            
            varlist = {'RCGA','problem','opts','fitnesspath','decodingpath','seed','executablescriptfilename'};
            uisave(varlist,'SavedRCGAsettings.mat');
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            varlist = {'RCGA','problem','opts','fitnesspath','decodingpath','seed','executablescriptfilename'};
            uiopen('load');
            for i = 1 : length(varlist)
                varname = char(varlist(i));
                if ~exist(varname,'var')
                    error('Loaded MAT file does not have the variable "%s".\nWas the selected file created by RCGAToolbox Mission Control?',varname);
                end
            end
            
            app.N_Variables.Value = problem.n_gene;
            app.N_Constraints.Value = problem.n_constraint;
            if isempty(fitnesspath)
                app.FitnessFunction.Value = func2str(problem.fitnessfun);
            else
                app.FitnessFunction.Value = strcat(fitnesspath,'/',func2str(problem.fitnessfun));
            end
            if isempty(decodingpath)
                app.DecodingFunction.Value = func2str(problem.decodingfun);
            else
                app.DecodingFunction.Value = strcat(decodingpath,'/',func2str(problem.decodingfun));
            end
            if strcmp(func2str(RCGA),'RCGA_UNDXMGG')
                app.AlgorithmSwitch.Value = 'UNDX/MGG';
            else
                app.AlgorithmSwitch.Value = 'REXstar/JGG';
            end
            app.PopulationSize.Value = opts.n_population;
            app.ChildrenSize.Value = opts.n_children;
            app.Pf.Value = opts.Pf;
            app.MaxGenerations.Value = opts.maxgen;
            app.MaxTime.Value = opts.maxtime / 60;
            app.ValueToBeReached.Value = opts.vtr;
            app.OutputIntervalGeneration.Value = opts.output_intvl;
            app.TransitionFileName.Value = opts.out_transition;
            app.BestIndividualFileName.Value = opts.out_best;
            app.FinalPopulationFileName.Value = opts.out_population;
            app.ReportFileName.Value = opts.out_report;
            app.Seed.Value = seed;
            app.N_Workers.Value = opts.n_par;
            if opts.local == 1
                app.LocalSwitch.Value = 'On';
            else
                app.LocalSwitch.Value = 'Off';
            end
            app.ExecutableScriptFileName.Value = executablescriptfilename;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'RCGAToolbox GUI';
            app.Problem = uipanel(app.UIFigure);
            app.Problem.Title = 'Problem';
            app.LabelNumericEditField = uilabel(app.Problem);
            app.LabelNumericEditField.Text = '# Decision Variables';
            app.N_Variables = uieditfield(app.Problem, 'numeric');
            app.N_Variables.Limits = [0 Inf];
            app.N_Variables.ValueDisplayFormat = '%.0f';
            app.N_Variables.Value = 10;
            app.LabelNumericEditField2 = uilabel(app.Problem);
            app.LabelNumericEditField2.Text = '# Constraints';
            app.N_Constraints = uieditfield(app.Problem, 'numeric');
            app.N_Constraints.Limits = [0 Inf];
            app.N_Constraints.ValueDisplayFormat = '%.0f';
            app.N_Constraints.Value = 2;
            app.LabelEditField = uilabel(app.Problem);
            app.LabelEditField.Text = 'Fitness Function File';
            app.FitnessFunction = uieditfield(app.Problem, 'text');
            app.FitnessFunction.Value = 'Fitness_Example.m';
            app.LabelEditField2 = uilabel(app.Problem);
            app.LabelEditField2.Text = 'Decoding Function File';
            app.DecodingFunction = uieditfield(app.Problem, 'text');
            app.DecodingFunction.Value = 'Decoding_Example.m';
            app.FitnessFunctionButton = uibutton(app.Problem, 'push');
            app.FitnessFunctionButton.ButtonPushedFcn = createCallbackFcn(app, @FitnessFunctionButtonPushed, true);
            app.FitnessFunctionButton.Text = 'Select';
            app.DecodingFunctionButton = uibutton(app.Problem, 'push');
            app.DecodingFunctionButton.ButtonPushedFcn = createCallbackFcn(app, @DecodingFunctionButtonPushed, true);
            app.DecodingFunctionButton.Text = 'Select';
            app.RCGAParameters = uipanel(app.UIFigure);
            app.RCGAParameters.Title = 'RCGA Parameters';
            app.Label15 = uilabel(app.RCGAParameters);
            app.Label15.Text = 'Population Size';
            app.PopulationSize = uieditfield(app.RCGAParameters, 'numeric');
            app.PopulationSize.Limits = [0 Inf];
            app.PopulationSize.ValueDisplayFormat = '%.0f';
            app.PopulationSize.Value = 300;
            app.Label16 = uilabel(app.RCGAParameters);
            app.Label16.Text = '# Children per Generation';
            app.ChildrenSize = uieditfield(app.RCGAParameters, 'numeric');
            app.ChildrenSize.Limits = [0 Inf];
            app.ChildrenSize.ValueDisplayFormat = '%.0f';
            app.ChildrenSize.Value = 300;
            app.Label17 = uilabel(app.RCGAParameters);
            app.Label17.Text = 'Pf';
            app.Pf = uieditfield(app.RCGAParameters, 'numeric');
            app.Pf.Limits = [0 Inf];
            app.Pf.ValueDisplayFormat = '%11.16g';
            app.Pf.Value = 0.45;
            app.UseRecommendedValueButton = uibutton(app.RCGAParameters, 'push');
            app.UseRecommendedValueButton.ButtonPushedFcn = createCallbackFcn(app, @UseRecommendedValueButtonPushed, true);
            app.UseRecommendedValueButton.Text = 'Use Recommended Values';
            app.Termination = uipanel(app.UIFigure);
            app.Termination.Title = 'Termination';
            app.Label18 = uilabel(app.Termination);
            app.Label18.Text = 'Max # Generations';
            app.MaxGenerations = uieditfield(app.Termination, 'numeric');
            app.MaxGenerations.Limits = [0 Inf];
            app.MaxGenerations.ValueDisplayFormat = '%.0f';
            app.MaxGenerations.Value = 1000;
            app.Label19 = uilabel(app.Termination);
            app.Label19.Text = 'Max Time (min)';
            app.MaxTime = uieditfield(app.Termination, 'numeric');
            app.MaxTime.Limits = [0 Inf];
            app.MaxTime.ValueDisplayFormat = '%11.16g';
            app.MaxTime.Value = 1;
            app.Label20 = uilabel(app.Termination);
            app.Label20.Text = 'Value To Be Reached';
            app.ValueToBeReached = uieditfield(app.Termination, 'numeric');
            app.ValueToBeReached.ValueDisplayFormat = '%e';
            app.Output = uipanel(app.UIFigure);
            app.Output.Title = 'Output';
            app.Label10 = uilabel(app.Output);
            app.Label10.Text = 'Transition File Name';
            app.TransitionFileName = uieditfield(app.Output, 'text');
            app.TransitionFileName.Value = 'Transition.txt';
            app.Label11 = uilabel(app.Output);
            app.Label11.Text = 'Best Individual File Name';
            app.BestIndividualFileName = uieditfield(app.Output, 'text');
            app.BestIndividualFileName.Value = 'BestIndividual.txt';
            app.Label12 = uilabel(app.Output);
            app.Label12.Text = 'Final Population File Name';
            app.FinalPopulationFileName = uieditfield(app.Output, 'text');
            app.FinalPopulationFileName.Value = 'FinalPopulation.txt';
            app.Label21 = uilabel(app.Output);
            app.Label21.Text = 'Output Interval Generation';
            app.OutputIntervalGeneration = uieditfield(app.Output, 'numeric');
            app.OutputIntervalGeneration.Limits = [0 Inf];
            app.OutputIntervalGeneration.ValueDisplayFormat = '%.0f';
            app.OutputIntervalGeneration.Value = 10;
            app.LabelEditField3 = uilabel(app.Output);
            app.LabelEditField3.Text = 'Report File Name';
            app.ReportFileName = uieditfield(app.Output, 'text');
            app.ReportFileName.Value = 'Report.mat';
            app.LaunchButton = uibutton(app.UIFigure, 'push');
            app.LaunchButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchButtonPushed, true);
            app.LaunchButton.Text = 'Launch';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Label23 = uilabel(app.UIFigure);
            app.Label23.Enable = 'off';
            app.Label23.Text = 'Messages will be shown in MATLAB Command Window.';
            app.Others = uipanel(app.UIFigure);
            app.Others.Title = 'Others';
            app.LabelNumericEditField3 = uilabel(app.Others);
            app.LabelNumericEditField3.Text = '# Workers ';
            app.N_Workers = uieditfield(app.Others, 'numeric');
            app.N_Workers.Limits = [1 Inf];
            app.N_Workers.ValueDisplayFormat = '%.0f';
            app.N_Workers.Value = 1;
            app.Label22 = uilabel(app.Others);
            app.Label22.Text = 'Random Seed';
            app.Seed = uieditfield(app.Others, 'numeric');
            app.Seed.Limits = [0 Inf];
            app.Seed.ValueDisplayFormat = '%.0f';
            app.LocalSwitch = uiswitch(app.Others, 'slider');
            app.LabelSwitch = uilabel(app.Others);
            app.LabelSwitch.Text = 'Local Optimizer';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Create an Executable Script With the Current Settings';
            app.LabelEditField4 = uilabel(app.Panel);
            app.LabelEditField4.Text = 'File Name';
            app.ExecutableScriptFileName = uieditfield(app.Panel, 'text');
            app.ExecutableScriptFileName.Value = 'CreatedExecutableScript.m';
            app.CreateButton = uibutton(app.Panel, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Text = 'Create';
            app.Label24 = uilabel(app.UIFigure);
            app.Label24.Enable = 'off';
            app.Label24.Text = 'Press ''Control + C'' to abort a running RCGA.';
            app.Label25 = uilabel(app.UIFigure);
            app.Label25.Text = 'RCGAToolbox Mission Control';
            app.Algorithm = uipanel(app.UIFigure);
            app.Algorithm.Title = 'Algorithm';
            app.AlgorithmSwitch = uiswitch(app.Algorithm, 'slider');
            app.AlgorithmSwitch.Items = {'UNDX/MGG', 'REXstar/JGG'};
            app.AlgorithmSwitch.Value = 'REXstar/JGG';
            app.Label27 = uilabel(app.UIFigure);
            app.Label27.Enable = 'off';
            app.Label27.Text = 'For a demo, press [Reset] and then [Launch].';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RCGAToolbox_MissionControl_R2021a

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end