classdef RCGAToolbox_Mission_Control_PE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure                   % RCGATo...
        Problem                 matlab.ui.container.Panel          % Problem
        LabelEditField          matlab.ui.control.Label            % Model ...
        Model                   matlab.ui.control.EditField        % modelE...
        LabelEditField2         matlab.ui.control.Label            % Decodi...
        DecodingFunction        matlab.ui.control.EditField        % decodi...
        ModelButton             matlab.ui.control.Button           % Select
        DecodingFunctionButton  matlab.ui.control.Button           % Select
        Label28                 matlab.ui.control.Label            % Measur...
        Measurement             matlab.ui.control.EditField        % measur...
        MeasurementButton       matlab.ui.control.Button           % Select
        RCGAParameters          matlab.ui.container.Panel          % RCGA P...
        Label15                 matlab.ui.control.Label            % Popula...
        PopulationSize          matlab.ui.control.NumericEditField % [0 Inf]
        Label16                 matlab.ui.control.Label            % # Chil...
        ChildrenSize            matlab.ui.control.NumericEditField % [0 Inf]
        UseRecommendedValueButton matlab.ui.control.Button % Use Recommende...
        Termination             matlab.ui.container.Panel          % Termin...
        Label18                 matlab.ui.control.Label            % Max # ...
        Max_N_Generation        matlab.ui.control.NumericEditField % [0 Inf]
        Label19                 matlab.ui.control.Label            % Max Ti...
        MaxTime                 matlab.ui.control.NumericEditField % [0 Inf]
        Label20                 matlab.ui.control.Label            % Value ...
        ValueToBeReached        matlab.ui.control.NumericEditField % [-Inf ...
        Output                  matlab.ui.container.Panel          % Output
        Label10                 matlab.ui.control.Label            % Transi...
        TransitionFileName      matlab.ui.control.EditField        % Transi...
        Label11                 matlab.ui.control.Label            % Best I...
        BestIndividualFileName  matlab.ui.control.EditField        % BestIn...
        Label12                 matlab.ui.control.Label            % Final ...
        FinalPopulationFileName matlab.ui.control.EditField        % FinalP...
        Label21                 matlab.ui.control.Label            % Output...
        OutputIntervalGeneration matlab.ui.control.NumericEditField % [0 Inf]
        LabelEditField3         matlab.ui.control.Label            % Report...
        ReportFileName          matlab.ui.control.EditField        % Report...
        LaunchButton            matlab.ui.control.Button           % Launch
        ResetButton             matlab.ui.control.Button           % Reset
        Label23                 matlab.ui.control.Label            % Messag...
        Others                  matlab.ui.container.Panel          % Others
        LabelNumericEditField3  matlab.ui.control.Label            % # Work...
        N_Workers               matlab.ui.control.NumericEditField % [1 Inf]
        Label22                 matlab.ui.control.Label            % Random...
        Seed                    matlab.ui.control.NumericEditField % [0 Inf]
        LocalSwitch             matlab.ui.control.Switch           % Off, On
        LabelSwitch             matlab.ui.control.Label            % Local ...
        Panel                   matlab.ui.container.Panel          % Create...
        LabelEditField4         matlab.ui.control.Label            % File Name
        ExecutableScriptFileName matlab.ui.control.EditField % CreatedExecu...
        CreateButton            matlab.ui.control.Button           % Create
        Label24                 matlab.ui.control.Label            % Type '...
        Label25                 matlab.ui.control.Label            % RCGATo...
        Algorithm               matlab.ui.container.Panel          % Algorithm
        AlgorithmSwitch         matlab.ui.control.Switch           % UNDX/M...
        Label27                 matlab.ui.control.Label            % For a ...
        SaveButton              matlab.ui.control.Button           % Save
        LoadButton              matlab.ui.control.Button           % Load
        Label29                 matlab.ui.control.Label            % Fast Flag
        FastFlag                matlab.ui.control.NumericEditField % [0 2]
    end

    properties (Access = public)
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % LaunchButton button pushed function
        function RunButtonPushed(app)
            pwd
            addpath(genpath('D:\cygwin64\home\maeda\GitHub\RCGAToolbox\source\'));
            
            [modelpath, modelfile] = fileparts(app.Model.Value);
            addpath(modelpath);
            model = modelfile;
            
            [decodingpath, decodingfile] = fileparts(app.DecodingFunction.Value);
            addpath(decodingpath);
            decodingfun = str2func(decodingfile);
            
%             [measurementpath, measurementfile] = fileparts(app.Measurement.Value);
            measurement = app.Measurement.Value;


            if strcmp(app.AlgorithmSwitch.Value,'UNDX/MGG')
                RCGA_PE = @RCGA_UNDXMGG_PE;
            else
                RCGA_PE = @RCGA_REXstarJGG_PE;
            end
            opts.n_population = app.PopulationSize.Value;
            opts.n_children = app.ChildrenSize.Value;
            opts.Pf = 0;
            opts.n_generation = app.Max_N_Generation.Value;
            opts.t_limit = 60 * app.MaxTime.Value;
            opts.vtr = app.ValueToBeReached.Value;
            opts.output_intvl = app.OutputIntervalGeneration.Value;
            opts.out_transition = app.TransitionFileName.Value;
            opts.out_best = app.BestIndividualFileName.Value;
            opts.out_population = app.FinalPopulationFileName.Value;
            opts.out_report = app.ReportFileName.Value;
            rng(app.Seed.Value);
            opts.n_par = app.N_Workers.Value;
            fast_flag = app.FastFlag.Value;
            if strcmp(app.LocalSwitch.Value,'On')
                opts.local = 1;
            else
                opts.local = 0;
            end
            
            RCGA_PE(model,decodingfun,measurement,fast_flag,[],opts);
            
            rmpath(modelpath);
            if ~strcmp(modelpath,decodingpath)
                decodingpath(fitnesspath);
            end
            
        end

        % UseRecommendedValueButton button pushed function
        function UseRecommendedValueButtonPushed(app)
            msg = 'The current values for "Population Size", "Children Size", and "Pf" will be lost. Would you like to continue?';
            title = 'Confirmation';
            selection = questdlg(msg,title,...
                'Yes','No','Yes');
            if strcmp(selection,'No')
                return;
            end
            app.PopulationSize.Value = 20 * app.N_Variables.Value;
            app.ChildrenSize.Value = 10 * app.N_Variables.Value;
            if app.N_Constraints.Value > 0
                app.Pf.Value = 0.45;
            else
                app.Pf.Value = 0;
            end
        end

        % ModelButton button pushed function
        function FitnessFunctionButtonPushed(app)
            ext = mexext;
            fileext = sprintf('*.m;*.mlx;*.%s',ext);
            description = sprintf('MATLAB Function Files (*.m,*.mlx,*.%s)',ext);
            [file,path,~] = uigetfile( ...
                {fileext,description;
                '*.*','All Files (*.*)'}, ...
                'Select a File');
            [~, file, ] = fileparts(file);
            app.Model.Value = strcat(path,file);
        end

        % DecodingFunctionButton button pushed function
        function DecodingFunctionButtonPushed(app)
            ext = mexext;
            fileext = sprintf('*.m;*.mlx;*.%s',ext);
            description = sprintf('MATLAB Function Files (*.m,*.mlx,*.%s)',ext);
            [file,path,~] = uigetfile( ...
                {fileext,description;
                '*.*','All Files (*.*)'}, ...
                'Select a File');
            [~, file, ] = fileparts(file);
            app.DecodingFunction.Value = strcat(path,file);
        end

        % ResetButton button pushed function
        function ResetButtonPushed(app)
            msg = 'The current settings will be lost. Would you like to continue?';
            title = 'Confirmation';
            selection = questdlg(msg,title,...
                'Yes','No','Yes');
            if strcmp(selection,'No')
                return;
            end
            app.N_Variables.Value = 9;
            app.N_Constraints.Value = 0;
            app.Model.Value = 'modelExample_m';
            app.DecodingFunction.Value = 'decodingExample';
            app.Measurement.Value = 'measurementExample';
            app.AlgorithmSwitch.Value = 'REXstar/JGG';
            app.PopulationSize.Value = 180;
            app.ChildrenSize.Value = 90;
            app.Pf.Value = 0;
            app.Max_N_Generation.Value = 10;
            app.MaxTime.Value = 5;
            app.ValueToBeReached.Value = 0;
            app.OutputIntervalGeneration.Value = 1;
            app.TransitionFileName.Value = 'Transition.txt';
            app.BestIndividualFileName.Value = 'BestIndividual.txt';
            app.FinalPopulationFileName.Value = 'FinalPopulation.txt';
            app.ReportFileName.Value = 'Report.mat';
            app.Seed.Value = 1;
            app.N_Workers.Value = 1;
            app.LocalSwitch.Value = 'Off';
            app.ExecutableScriptFileName.Value = 'CreatedExecutableScript.m';
        end

        % CreateButton button pushed function
        function CreateButtonPushed(app)
            CreateExecutableScript(app);
        end

        % SaveButton button pushed function
        function SaveButtonPushed(app)
            problem.n_gene = app.N_Variables.Value;
            problem.n_constraint = app.N_Constraints.Value;
            
            [modelpath, modelfile] = fileparts(app.Model.Value);
            model = str2func(modelfile);
            
            [decodingpath, decodingfile] = fileparts(app.DecodingFunction.Value);
            problem.decodingfun = str2func(decodingfile);
            
            [measurementpath, measurementfile] = fileparts(app.Measurement.Value);
            problem.decodingfun = str2func(measurementfile);
            
            if strcmp(app.AlgorithmSwitch.Value,'UNDX/MGG')
                RCGA = @RCGA_UNDXMGG;
            else
                RCGA = @RCGA_REXstarJGG;
            end
            opts.n_population = app.PopulationSize.Value;
            opts.n_children = app.ChildrenSize.Value;
            opts.Pf = app.Pf.Value;
            opts.n_generation = app.Max_N_Generation.Value;
            opts.t_limit = 60 * app.MaxTime.Value;
            opts.vtr = app.ValueToBeReached.Value;
            opts.output_intvl = app.OutputIntervalGeneration.Value;
            opts.out_transition = app.TransitionFileName.Value;
            opts.out_best = app.BestIndividualFileName.Value;
            opts.out_population = app.FinalPopulationFileName.Value;
            opts.out_report = app.ReportFileName.Value;
            seed = app.Seed.Value;
            opts.n_par = app.N_Workers.Value;
            if strcmp(app.LocalSwitch.Value,'On')
                opts.local = 1;
            else
                opts.local = 0;
            end
            executablescriptfilename = app.ExecutableScriptFileName.Value;
            
            varlist = {'RCGA','problem','opts','fitnesspath','decodingpath','seed','executablescriptfilename'};
            uisave(varlist,'SavedRCGAsettings.mat');
        end

        % LoadButton button pushed function
        function LoadButtonPushed(app)
            varlist = {'RCGA','problem','opts','fitnesspath','decodingpath','seed','executablescriptfilename'};
            uiopen('load');
            for i = 1 : length(varlist)
                varname = char(varlist(i));
                if ~exist(varname,'var')
                    error('Loaded MAT file does not have the variable "%s".\nWas the selected file created by RCGAToolbox Mission Control?',varname);
                end
            end
            
            app.N_Variables.Value = problem.n_gene;
            app.N_Constraints.Value = problem.n_constraint;
            app.Model.Value = strcat(fitnesspath,'/',func2str(problem.fitnessfun));
            app.DecodingFunction.Value = strcat(decodingpath,'/',func2str(problem.decodingfun));
            if strcmp(func2str(RCGA),'RCGA_UNDXMGG')
                app.AlgorithmSwitch.Value = 'UNDX/MGG';
            else
                app.AlgorithmSwitch.Value = 'REXstar/JGG';
            end
            app.PopulationSize.Value = opts.n_population;
            app.ChildrenSize.Value = opts.n_children;
            app.Pf.Value = opts.Pf;
            app.Max_N_Generation.Value = opts.n_generation;
            app.MaxTime.Value = opts.t_limit / 60;
            app.ValueToBeReached.Value = opts.vtr;
            app.OutputIntervalGeneration.Value = opts.output_intvl;
            app.TransitionFileName.Value = opts.out_transition;
            app.BestIndividualFileName.Value = opts.out_best;
            app.FinalPopulationFileName.Value = opts.out_population;
            app.ReportFileName.Value = opts.out_report;
            app.Seed.Value = seed;
            app.N_Workers.Value = opts.n_par;
            if opts.local == 1
                app.LocalSwitch.Value = 'On';
            else
                app.LocalSwitch.Value = 'Off';
            end
            app.ExecutableScriptFileName.Value = executablescriptfilename;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'RCGAToolbox Mission Control';
            setAutoResize(app, app.UIFigure, true)
            app.Problem = uipanel(app.UIFigure);
            app.Problem.Title = 'Problem';
            app.Problem.Units = 'pixels';
            app.LabelEditField = uilabel(app.Problem);
            app.LabelEditField.Text = 'Model File';
            app.Model = uieditfield(app.Problem, 'text');
            app.Model.Value = 'modelExample_m.m';
            app.LabelEditField2 = uilabel(app.Problem);
            app.LabelEditField2.Text = 'Decoding Function File';
            app.DecodingFunction = uieditfield(app.Problem, 'text');
            app.DecodingFunction.Value = 'decodingExample.m';
            app.ModelButton = uibutton(app.Problem, 'push');
            app.ModelButton.ButtonPushedFcn = createCallbackFcn(app, @FitnessFunctionButtonPushed);
            app.ModelButton.Text = 'Select';
            app.DecodingFunctionButton = uibutton(app.Problem, 'push');
            app.DecodingFunctionButton.ButtonPushedFcn = createCallbackFcn(app, @DecodingFunctionButtonPushed);
            app.DecodingFunctionButton.Text = 'Select';
            app.Label28 = uilabel(app.Problem);
            app.Label28.Text = 'Measurement File';
            app.Measurement = uieditfield(app.Problem, 'text');
            app.Measurement.Value = 'measurementExample.xls';
            app.MeasurementButton = uibutton(app.Problem, 'push');
            app.MeasurementButton.Text = 'Select';
            app.RCGAParameters = uipanel(app.UIFigure);
            app.RCGAParameters.Title = 'RCGA Parameters';
            app.RCGAParameters.Units = 'pixels';
            app.Label15 = uilabel(app.RCGAParameters);
            app.Label15.Text = 'Population Size';
            app.PopulationSize = uieditfield(app.RCGAParameters, 'numeric');
            app.PopulationSize.Limits = [0 Inf];
            app.PopulationSize.ValueDisplayFormat = '%.0f';
            app.PopulationSize.Value = 50;
            app.Label16 = uilabel(app.RCGAParameters);
            app.Label16.Text = '# Children per Generation';
            app.ChildrenSize = uieditfield(app.RCGAParameters, 'numeric');
            app.ChildrenSize.Limits = [0 Inf];
            app.ChildrenSize.ValueDisplayFormat = '%.0f';
            app.ChildrenSize.Value = 25;
            app.UseRecommendedValueButton = uibutton(app.RCGAParameters, 'push');
            app.UseRecommendedValueButton.ButtonPushedFcn = createCallbackFcn(app, @UseRecommendedValueButtonPushed);
            app.UseRecommendedValueButton.Text = 'Use Recommended Values';
            app.Termination = uipanel(app.UIFigure);
            app.Termination.Title = 'Termination';
            app.Termination.Units = 'pixels';
            app.Label18 = uilabel(app.Termination);
            app.Label18.Text = 'Max # Generations';
            app.Max_N_Generation = uieditfield(app.Termination, 'numeric');
            app.Max_N_Generation.Limits = [0 Inf];
            app.Max_N_Generation.ValueDisplayFormat = '%.0f';
            app.Max_N_Generation.Value = 100;
            app.Label19 = uilabel(app.Termination);
            app.Label19.Text = 'Max Time (min)';
            app.MaxTime = uieditfield(app.Termination, 'numeric');
            app.MaxTime.Limits = [0 Inf];
            app.MaxTime.ValueDisplayFormat = '%11.16g';
            app.MaxTime.Value = 1;
            app.Label20 = uilabel(app.Termination);
            app.Label20.Text = 'Value To Be Reached';
            app.ValueToBeReached = uieditfield(app.Termination, 'numeric');
            app.ValueToBeReached.ValueDisplayFormat = '%e';
            app.Output = uipanel(app.UIFigure);
            app.Output.Title = 'Output';
            app.Output.Units = 'pixels';
            app.Label10 = uilabel(app.Output);
            app.Label10.Text = 'Transition File Name';
            app.TransitionFileName = uieditfield(app.Output, 'text');
            app.TransitionFileName.Value = 'Transition.txt';
            app.Label11 = uilabel(app.Output);
            app.Label11.Text = 'Best Individual File Name';
            app.BestIndividualFileName = uieditfield(app.Output, 'text');
            app.BestIndividualFileName.Value = 'BestIndividual.txt';
            app.Label12 = uilabel(app.Output);
            app.Label12.Text = 'Final Population File Name';
            app.FinalPopulationFileName = uieditfield(app.Output, 'text');
            app.FinalPopulationFileName.Value = 'FinalPopulation.txt';
            app.Label21 = uilabel(app.Output);
            app.Label21.Text = 'Output Interval Generation';
            app.OutputIntervalGeneration = uieditfield(app.Output, 'numeric');
            app.OutputIntervalGeneration.Limits = [0 Inf];
            app.OutputIntervalGeneration.ValueDisplayFormat = '%.0f';
            app.OutputIntervalGeneration.Value = 1;
            app.LabelEditField3 = uilabel(app.Output);
            app.LabelEditField3.Text = 'Report File Name';
            app.ReportFileName = uieditfield(app.Output, 'text');
            app.ReportFileName.Value = 'Report.mat';
            app.LaunchButton = uibutton(app.UIFigure, 'push');
            app.LaunchButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed);
            app.LaunchButton.Text = 'Launch';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed);
            app.ResetButton.Text = 'Reset';
            app.Label23 = uilabel(app.UIFigure);
            app.Label23.Enable = 'off';
            app.Label23.Text = 'Messages will be shown in MATLAB Command Window';
            app.Others = uipanel(app.UIFigure);
            app.Others.Title = 'Others';
            app.Others.Units = 'pixels';
            app.LabelNumericEditField3 = uilabel(app.Others);
            app.LabelNumericEditField3.Text = '# Workers ';
            app.N_Workers = uieditfield(app.Others, 'numeric');
            app.N_Workers.Limits = [1 Inf];
            app.N_Workers.ValueDisplayFormat = '%.0f';
            app.N_Workers.Value = 1;
            app.Label22 = uilabel(app.Others);
            app.Label22.Text = 'Random Seed';
            app.Seed = uieditfield(app.Others, 'numeric');
            app.Seed.Limits = [0 Inf];
            app.Seed.ValueDisplayFormat = '%.0f';
            app.Seed.Value = 1;
            app.LocalSwitch = uiswitch(app.Others, 'slider');
            app.LabelSwitch = uilabel(app.Others);
            app.LabelSwitch.Text = 'Local Optimizer';
            app.Label29 = uilabel(app.Others);
            app.Label29.Text = 'Fast Flag';
            app.FastFlag = uieditfield(app.Others, 'numeric');
            app.FastFlag.Limits = [0 2];
            app.FastFlag.ValueDisplayFormat = '%.0f';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Create an Executable Script With the Current Settings';
            app.Panel.Units = 'pixels';
            app.LabelEditField4 = uilabel(app.Panel);
            app.LabelEditField4.Text = 'File Name';
            app.ExecutableScriptFileName = uieditfield(app.Panel, 'text');
            app.ExecutableScriptFileName.Value = 'CreatedExecutableScript.m';
            app.CreateButton = uibutton(app.Panel, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed);
            app.CreateButton.Text = 'Create';
            app.Label24 = uilabel(app.UIFigure);
            app.Label24.Enable = 'off';
            app.Label24.Text = 'Type ''Ctrl + C'' to abort a launched RCGA';
            app.Label25 = uilabel(app.UIFigure);
            app.Label25.Text = 'RCGAToolbox PE Mission Control';
            app.Algorithm = uipanel(app.UIFigure);
            app.Algorithm.Title = 'Algorithm';
            app.Algorithm.Units = 'pixels';
            app.AlgorithmSwitch = uiswitch(app.Algorithm, 'slider');
            app.AlgorithmSwitch.Items = {'UNDX/MGG', 'REXstar/JGG'};
            app.AlgorithmSwitch.Value = 'REXstar/JGG';
            app.Label27 = uilabel(app.UIFigure);
            app.Label27.Enable = 'off';
            app.Label27.Text = 'For a demo, press [Reset] and then [Launch]';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed);
            app.SaveButton.Text = 'Save';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed);
            app.LoadButton.Text = 'Load';
        end
    end

    methods (Access = public)

        % Construct app
        function app = RCGAToolbox_Mission_Control_PE()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end