classdef UNDXMGG_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure                   % RCGATo...
        Problem                 matlab.ui.container.Panel          % Problem
        LabelNumericEditField   matlab.ui.control.Label            % # Vari...
        N_Variables             matlab.ui.control.NumericEditField % [0 Inf]
        LabelNumericEditField2  matlab.ui.control.Label            % # Cons...
        N_Constraints           matlab.ui.control.NumericEditField % [0 Inf]
        LabelEditField          matlab.ui.control.Label            % Fitnes...
        FitnessFunction         matlab.ui.control.EditField        % fitnes...
        LabelEditField2         matlab.ui.control.Label            % Decodi...
        DecodingFunction        matlab.ui.control.EditField        % decodi...
        FitnessFunctionButton   matlab.ui.control.Button           % Select
        DecodingFunctionButton  matlab.ui.control.Button           % Select
        Label                   matlab.ui.control.Label            % UNDX/MGG
        RCGAParameters          matlab.ui.container.Panel          % RCGA P...
        Label15                 matlab.ui.control.Label            % Popula...
        PopulationSize          matlab.ui.control.NumericEditField % [0 Inf]
        Label16                 matlab.ui.control.Label            % # Chil...
        ChildrenSize            matlab.ui.control.NumericEditField % [0 Inf]
        Label17                 matlab.ui.control.Label            % Pf
        Pf                      matlab.ui.control.NumericEditField % [0 Inf]
        UseRecommendedValueButton matlab.ui.control.Button % Use Recommende...
        Termination             matlab.ui.container.Panel          % Termin...
        Label18                 matlab.ui.control.Label            % Max # ...
        Max_N_Generation        matlab.ui.control.NumericEditField % [0 Inf]
        Label19                 matlab.ui.control.Label            % Max Ti...
        MaxTime                 matlab.ui.control.NumericEditField % [0 Inf]
        Label20                 matlab.ui.control.Label            % Value ...
        ValueToBeReached        matlab.ui.control.NumericEditField % [-Inf ...
        Output                  matlab.ui.container.Panel          % Output
        Label10                 matlab.ui.control.Label            % Transi...
        TransitionFileName      matlab.ui.control.EditField        % Transi...
        Label11                 matlab.ui.control.Label            % Best I...
        BestIndividualFileName  matlab.ui.control.EditField        % BestIn...
        Label12                 matlab.ui.control.Label            % Final ...
        FinalPopulationFileName matlab.ui.control.EditField        % FinalP...
        Label21                 matlab.ui.control.Label            % Output...
        OutputIntervalGeneration matlab.ui.control.NumericEditField % [0 Inf]
        LabelEditField3         matlab.ui.control.Label            % Report...
        ReportFileName          matlab.ui.control.EditField        % Report...
        RunButton               matlab.ui.control.Button           % Run
        ResetButton             matlab.ui.control.Button           % Reset ...
        Label23                 matlab.ui.control.Label            % Messag...
        Button                  matlab.ui.control.Button           % Create
        Others                  matlab.ui.container.Panel          % Others
        LabelNumericEditField3  matlab.ui.control.Label            % # Work...
        N_Workers               matlab.ui.control.NumericEditField % [1 Inf]
        Label22                 matlab.ui.control.Label            % Random...
        Seed                    matlab.ui.control.NumericEditField % [0 Inf]
        LocalSwitch             matlab.ui.control.Switch           % Off, On
        LabelSwitch             matlab.ui.control.Label            % Local ...
        LabelEditField4         matlab.ui.control.Label            % File Name
        ExecutableScriptFileName matlab.ui.control.EditField % CreatedExecu...
        Panel                   matlab.ui.container.Panel          % Create...
        Label24                 matlab.ui.control.Label            % Type '...
    end

    properties (Access = public)
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % RunButton button pushed function
        function RunButtonPushed(app)
            pwd
            addpath(genpath('D:\cygwin64\home\maeda\GitHub\RCGAToolbox\source\'));
            problem.n_gene = app.N_Variables.Value;
            problem.n_constraint = app.N_Constraints.Value;
            [fitnesspath, fitnessfile] = fileparts(app.FitnessFunction.Value);
            addpath(fitnesspath);
            problem.fitnessfun = str2func(fitnessfile);
            [decodingpath, decodingfile] = fileparts(app.DecodingFunction.Value);
            addpath(decodingpath);
            problem.decodingfun = str2func(decodingfile);
            opts.n_population = app.PopulationSize.Value;
            opts.n_children = app.ChildrenSize.Value;
            opts.Pf = app.Pf.Value;
            opts.n_generation = app.Max_N_Generation.Value;
            opts.t_limit = 60 * app.MaxTime.Value;
            opts.vtr = app.ValueToBeReached.Value;
            opts.output_intvl = app.OutputIntervalGeneration.Value;
            opts.out_transition = app.TransitionFileName.Value;
            opts.out_best = app.BestIndividualFileName.Value;
            opts.out_population = app.FinalPopulationFileName.Value;
            opts.out_report = app.ReportFileName.Value;
            rng(app.Seed.Value);
            opts.n_par = app.N_Workers.Value;
            
            if strcmp(app.LocalSwitch.Value,'On')
                opts.local = 1;
            else
                opts.local = 0;
            end
            RCGA_UNDXMGG(problem,opts);
            rmpath(fitnesspath);
            if ~strcmp(fitnesspath,decodingpath)
                decodingpath(fitnesspath);
            end
            
        end

        % UseRecommendedValueButton button pushed function
        function UseRecommendedValueButtonPushed(app)
            msg = 'The current values for "Population Size", "Children Size", and "Pf" will be lost. Would you like to continue?';
            title = 'Confirmation';
            selection = questdlg(msg,title,...
                'Yes','No','Yes');
            if strcmp(selection,'No')
                return;
            end
            app.PopulationSize.Value = 20 * app.N_Variables.Value;
            app.ChildrenSize.Value = 10 * app.N_Variables.Value;
            if app.N_Constraints.Value > 0
                app.Pf.Value = 0.45;
            else
                app.Pf.Value = 0;
            end
        end

        % FitnessFunctionButton button pushed function
        function FitnessFunctionButtonPushed(app)
            ext = mexext;
            fileext = sprintf('*.m;*.mlx;*.%s',ext);
            description = sprintf('MATLAB Function Files (*.m,*.mlx,*.%s)',ext);
            [file,path,~] = uigetfile( ...
                {fileext,description;
                '*.*','All Files (*.*)'}, ...
                'Select a File');
            app.FitnessFunction.Value = strcat(path,file);
        end

        % DecodingFunctionButton button pushed function
        function DecodingFunctionButtonPushed(app)
            ext = mexext;
            fileext = sprintf('*.m;*.mlx;*.%s',ext);
            description = sprintf('MATLAB Function Files (*.m,*.mlx,*.%s)',ext);
            [file,path,~] = uigetfile( ...
                {fileext,description;
                '*.*','All Files (*.*)'}, ...
                'Select a File');
            app.DecodingFunction.Value = strcat(path,file);
        end

        % ResetButton button pushed function
        function ResetButtonPushed(app)
            msg = 'The current settings will be lost. Would you like to continue?';
            title = 'Confirmation';
            selection = questdlg(msg,title,...
                'Yes','No','Yes');
            if strcmp(selection,'No')
                return;
            end
            app.N_Variables.Value = 10;
            app.N_Constraints.Value = 2;
            app.FitnessFunction.Value = 'fitnessExample.m';
            app.DecodingFunction.Value = 'decodingExample.m';
            app.PopulationSize.Value = 200;
            app.ChildrenSize.Value = 100;
            app.Pf.Value = 0.45;
            app.Max_N_Generation.Value = 1000;
            app.MaxTime.Value = 1;
            app.ValueToBeReached.Value = 0;
            app.OutputIntervalGeneration.Value = 10;
            app.TransitionFileName.Value = 'Transition.txt';
            app.BestIndividualFileName.Value = 'BestIndividual.txt';
            app.FinalPopulationFileName.Value = 'FinalPopulation.txt';
            app.ReportFileName.Value = 'Report.mat';
            app.Seed.Value = 1;
            app.N_Workers.Value = 1;
            app.LocalSwitch.Value = 'Off';
        end

        % Button button pushed function
        function GenerateButtonPushed(app)
            CreateExecutableScript_UNDXMGG(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'RCGAToolbox Mission Control';
            setAutoResize(app, app.UIFigure, true)
            app.Problem = uipanel(app.UIFigure);
            app.Problem.Title = 'Problem';
            app.Problem.Units = 'pixels';
            app.LabelNumericEditField = uilabel(app.Problem);
            app.LabelNumericEditField.Text = '# Variables';
            app.N_Variables = uieditfield(app.Problem, 'numeric');
            app.N_Variables.Limits = [0 Inf];
            app.N_Variables.ValueDisplayFormat = '%.0f';
            app.N_Variables.Value = 10;
            app.LabelNumericEditField2 = uilabel(app.Problem);
            app.LabelNumericEditField2.Text = '# Constraints';
            app.N_Constraints = uieditfield(app.Problem, 'numeric');
            app.N_Constraints.Limits = [0 Inf];
            app.N_Constraints.ValueDisplayFormat = '%.0f';
            app.N_Constraints.Value = 2;
            app.LabelEditField = uilabel(app.Problem);
            app.LabelEditField.Text = 'Fitness Function File';
            app.FitnessFunction = uieditfield(app.Problem, 'text');
            app.FitnessFunction.Value = 'fitnessExample.m';
            app.LabelEditField2 = uilabel(app.Problem);
            app.LabelEditField2.Text = 'Decoding Function File';
            app.DecodingFunction = uieditfield(app.Problem, 'text');
            app.DecodingFunction.Value = 'decodingExample.m';
            app.FitnessFunctionButton = uibutton(app.Problem, 'push');
            app.FitnessFunctionButton.ButtonPushedFcn = createCallbackFcn(app, @FitnessFunctionButtonPushed);
            app.FitnessFunctionButton.Text = 'Select';
            app.DecodingFunctionButton = uibutton(app.Problem, 'push');
            app.DecodingFunctionButton.ButtonPushedFcn = createCallbackFcn(app, @DecodingFunctionButtonPushed);
            app.DecodingFunctionButton.Text = 'Select';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'UNDX/MGG';
            app.RCGAParameters = uipanel(app.UIFigure);
            app.RCGAParameters.Title = 'RCGA Parameters';
            app.RCGAParameters.Units = 'pixels';
            app.Label15 = uilabel(app.RCGAParameters);
            app.Label15.Text = 'Population Size';
            app.PopulationSize = uieditfield(app.RCGAParameters, 'numeric');
            app.PopulationSize.Limits = [0 Inf];
            app.PopulationSize.ValueDisplayFormat = '%.0f';
            app.PopulationSize.Value = 200;
            app.Label16 = uilabel(app.RCGAParameters);
            app.Label16.Text = '# Children per Generation';
            app.ChildrenSize = uieditfield(app.RCGAParameters, 'numeric');
            app.ChildrenSize.Limits = [0 Inf];
            app.ChildrenSize.ValueDisplayFormat = '%.0f';
            app.ChildrenSize.Value = 100;
            app.Label17 = uilabel(app.RCGAParameters);
            app.Label17.Text = 'Pf';
            app.Pf = uieditfield(app.RCGAParameters, 'numeric');
            app.Pf.Limits = [0 Inf];
            app.Pf.ValueDisplayFormat = '%11.16g';
            app.Pf.Value = 0.45;
            app.UseRecommendedValueButton = uibutton(app.RCGAParameters, 'push');
            app.UseRecommendedValueButton.ButtonPushedFcn = createCallbackFcn(app, @UseRecommendedValueButtonPushed);
            app.UseRecommendedValueButton.Text = 'Use Recommended Values';
            app.Termination = uipanel(app.UIFigure);
            app.Termination.Title = 'Termination';
            app.Termination.Units = 'pixels';
            app.Label18 = uilabel(app.Termination);
            app.Label18.Text = 'Max # Generations';
            app.Max_N_Generation = uieditfield(app.Termination, 'numeric');
            app.Max_N_Generation.Limits = [0 Inf];
            app.Max_N_Generation.ValueDisplayFormat = '%.0f';
            app.Max_N_Generation.Value = 1000;
            app.Label19 = uilabel(app.Termination);
            app.Label19.Text = 'Max Time (min)';
            app.MaxTime = uieditfield(app.Termination, 'numeric');
            app.MaxTime.Limits = [0 Inf];
            app.MaxTime.ValueDisplayFormat = '%11.16g';
            app.MaxTime.Value = 1;
            app.Label20 = uilabel(app.Termination);
            app.Label20.Text = 'Value To Be Reached';
            app.ValueToBeReached = uieditfield(app.Termination, 'numeric');
            app.ValueToBeReached.ValueDisplayFormat = '%e';
            app.Output = uipanel(app.UIFigure);
            app.Output.Title = 'Output';
            app.Output.Units = 'pixels';
            app.Label10 = uilabel(app.Output);
            app.Label10.Text = 'Transition File Name';
            app.TransitionFileName = uieditfield(app.Output, 'text');
            app.TransitionFileName.Value = 'Transition.txt';
            app.Label11 = uilabel(app.Output);
            app.Label11.Text = 'Best Individual File Name';
            app.BestIndividualFileName = uieditfield(app.Output, 'text');
            app.BestIndividualFileName.Value = 'BestIndividual.txt';
            app.Label12 = uilabel(app.Output);
            app.Label12.Text = 'Final Population File Name';
            app.FinalPopulationFileName = uieditfield(app.Output, 'text');
            app.FinalPopulationFileName.Value = 'FinalPopulation.txt';
            app.Label21 = uilabel(app.Output);
            app.Label21.Text = 'Output Interval Generation';
            app.OutputIntervalGeneration = uieditfield(app.Output, 'numeric');
            app.OutputIntervalGeneration.Limits = [0 Inf];
            app.OutputIntervalGeneration.ValueDisplayFormat = '%.0f';
            app.OutputIntervalGeneration.Value = 10;
            app.LabelEditField3 = uilabel(app.Output);
            app.LabelEditField3.Text = 'Report File Name';
            app.ReportFileName = uieditfield(app.Output, 'text');
            app.ReportFileName.Value = 'Report.mat';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed);
            app.RunButton.Text = 'Run';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed);
            app.ResetButton.Text = 'Reset Settings';
            app.Label23 = uilabel(app.UIFigure);
            app.Label23.Enable = 'off';
            app.Label23.Text = 'Messages shown in MATLAB Command Window';
            app.Others = uipanel(app.UIFigure);
            app.Others.Title = 'Others';
            app.Others.Units = 'pixels';
            app.LabelNumericEditField3 = uilabel(app.Others);
            app.LabelNumericEditField3.Text = '# Workers ';
            app.N_Workers = uieditfield(app.Others, 'numeric');
            app.N_Workers.Limits = [1 Inf];
            app.N_Workers.ValueDisplayFormat = '%.0f';
            app.N_Workers.Value = 1;
            app.Label22 = uilabel(app.Others);
            app.Label22.Text = 'Random Seed';
            app.Seed = uieditfield(app.Others, 'numeric');
            app.Seed.Limits = [0 Inf];
            app.Seed.ValueDisplayFormat = '%.0f';
            app.Seed.Value = 1;
            app.LocalSwitch = uiswitch(app.Others, 'slider');
            app.LabelSwitch = uilabel(app.Others);
            app.LabelSwitch.Text = 'Local Optimizer';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Create an Executable Script With the Current Settings';
            app.Panel.Units = 'pixels';
            app.LabelEditField4 = uilabel(app.Panel);
            app.LabelEditField4.Text = 'File Name';
            app.ExecutableScriptFileName = uieditfield(app.Panel, 'text');
            app.ExecutableScriptFileName.Value = 'CreatedExecutableScript.m';
            app.Button = uibutton(app.Panel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed);
            app.Button.Text = 'Create';
            app.Label24 = uilabel(app.UIFigure);
            app.Label24.Enable = 'off';
            app.Label24.Text = 'Type ''Ctrl + C'' to stop RCGA';
        end
    end

    methods (Access = public)

        % Construct app
        function app = UNDXMGG_GUI()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end